classdef equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ExtremelyHighEndSliderLabel  matlab.ui.control.Label
        ExtremelyHighEndSlider       matlab.ui.control.Slider
        HighEndSliderLabel           matlab.ui.control.Label
        HighEndSlider                matlab.ui.control.Slider
        PresenceRangeSliderLabel     matlab.ui.control.Label
        PresenceRangeSlider          matlab.ui.control.Slider
        UpperMidrangeSliderLabel     matlab.ui.control.Label
        UpperMidrangeSlider          matlab.ui.control.Slider
        MiddleMidrangeSliderLabel    matlab.ui.control.Label
        MiddleMidrangeSlider         matlab.ui.control.Slider
        LowerMidrangeSliderLabel     matlab.ui.control.Label
        LowerMidrangeSlider          matlab.ui.control.Slider
        UpperBassSliderLabel         matlab.ui.control.Label
        UpperBassSlider              matlab.ui.control.Slider
        MidBassSliderLabel           matlab.ui.control.Label
        MidBassSlider                matlab.ui.control.Slider
        LowBassSliderLabel           matlab.ui.control.Label
        LowBassSlider                matlab.ui.control.Slider
        DeepBassSlider               matlab.ui.control.Slider
        DeepBassSliderLabel          matlab.ui.control.Label
        Loadamp3fileButton           matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Loadamp3fileButton
        function Loadamp3fileButtonPushed(app, event)

            [file,path] = uigetfile('*.mp3');  
            [~, fs] = audioread(fullfile(path,file));
            sample = [1, 2*fs];
            [xn, fs] = audioread(fullfile(path,file),sample);
            x = xn(:,1);
            x = x./max(x); % Normalización de la señal
            n = length(x);
            t = (0:n-1)/fs; % Creando un vector de tiempo
            % Graficando la señal obtenida      
            plot(app.UIAxes,t,x);
            grid(app.UIAxes, 'on')


            Fx = fft(x); % Algoritmo para calcular los valores de la Transformada de Fourier
            Fxs = fftshift(Fx); % Corrimiento de la transformada para obtener valores negativos y positivos. 
            f = (-n/2:n/2-1)*fs/n; % Creación del vector de Frecuencias
            
            % Grafica de la magnitud de la transformada de Fourier
            plot(app.UIAxes2,f,abs(Fxs));
            grid(app.UIAxes2, 'on')
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {661, 661};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {290, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Loadamp3fileButton = uibutton(app.LeftPanel, 'push');
            app.Loadamp3fileButton.ButtonPushedFcn = createCallbackFcn(app, @Loadamp3fileButtonPushed, true);
            app.Loadamp3fileButton.Text = 'Load a mp3 file';
            app.DeepBassSliderLabel = uilabel(app.LeftPanel);
            app.DeepBassSliderLabel.Text = 'Deep Bass';
            app.DeepBassSlider = uislider(app.LeftPanel);
            app.DeepBassSlider.Limits = [-3 3];
            app.DeepBassSlider.Value = 1;
            app.LowBassSlider = uislider(app.LeftPanel);
            app.LowBassSlider.Limits = [-3 3];
            app.LowBassSlider.Value = 1;
            app.LowBassSliderLabel = uilabel(app.LeftPanel);
            app.LowBassSliderLabel.Text = 'Low Bass';
            app.MidBassSlider = uislider(app.LeftPanel);
            app.MidBassSlider.Limits = [-3 3];
            app.MidBassSlider.Value = 1;
            app.MidBassSliderLabel = uilabel(app.LeftPanel);
            app.MidBassSliderLabel.Text = 'Mid Bass';
            app.UpperBassSlider = uislider(app.LeftPanel);
            app.UpperBassSlider.Limits = [-3 3];
            app.UpperBassSlider.Value = 1;
            app.UpperBassSliderLabel = uilabel(app.LeftPanel);
            app.UpperBassSliderLabel.Text = 'Upper Bass';
            app.LowerMidrangeSlider = uislider(app.LeftPanel);
            app.LowerMidrangeSlider.Limits = [-3 3];
            app.LowerMidrangeSlider.Value = 1;
            app.LowerMidrangeSliderLabel = uilabel(app.LeftPanel);
            app.LowerMidrangeSliderLabel.Text = 'Lower Midrange';
            app.MiddleMidrangeSlider = uislider(app.LeftPanel);
            app.MiddleMidrangeSlider.Limits = [-3 3];
            app.MiddleMidrangeSlider.Value = 1;
            app.MiddleMidrangeSliderLabel = uilabel(app.LeftPanel);
            app.MiddleMidrangeSliderLabel.Text = 'Middle Midrange';
            app.UpperMidrangeSlider = uislider(app.LeftPanel);
            app.UpperMidrangeSlider.Limits = [-3 3];
            app.UpperMidrangeSlider.Value = 1;
            app.UpperMidrangeSliderLabel = uilabel(app.LeftPanel);
            app.UpperMidrangeSliderLabel.Text = 'Upper Midrange';
            app.PresenceRangeSlider = uislider(app.LeftPanel);
            app.PresenceRangeSlider.Limits = [-3 3];
            app.PresenceRangeSlider.Value = 1;
            app.PresenceRangeSliderLabel = uilabel(app.LeftPanel);
            app.PresenceRangeSliderLabel.Text = 'Presence Range';
            app.HighEndSlider = uislider(app.LeftPanel);
            app.HighEndSlider.Limits = [-3 3];
            app.HighEndSlider.Value = 1;
            app.HighEndSliderLabel = uilabel(app.LeftPanel);
            app.HighEndSliderLabel.Text = 'High End';
            app.ExtremelyHighEndSlider = uislider(app.LeftPanel);
            app.ExtremelyHighEndSlider.Limits = [-3 3];
            app.ExtremelyHighEndSlider.Value = 1;
            app.ExtremelyHighEndSliderLabel = uilabel(app.LeftPanel);
            app.ExtremelyHighEndSliderLabel.Text = 'Extremely High End';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Señal Grabada')
            xlabel(app.UIAxes, 'tiempo [s]')
            ylabel(app.UIAxes, 'Amplitud [u.a.]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Magnitud de la Transformada de Fourier de la Señal')
            xlabel(app.UIAxes2, 'Frecuencia [Hz]')
            ylabel(app.UIAxes2, 'Amplitud [u.a.]')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = equalizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end